classdef matrix_system < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        EditField_rang         matlab.ui.control.NumericEditField
        Label_6                matlab.ui.control.Label
        EditField_det          matlab.ui.control.NumericEditField
        Label_5                matlab.ui.control.Label
        clear_matrix           matlab.ui.control.Button
        Button_determ_2        matlab.ui.control.Button
        Button_determ          matlab.ui.control.Button
        matrix1_9              matlab.ui.control.NumericEditField
        matrix1_8              matlab.ui.control.NumericEditField
        matrix1_7              matlab.ui.control.NumericEditField
        matrix1_6              matlab.ui.control.NumericEditField
        matrix1_5              matlab.ui.control.NumericEditField
        matrix1_4              matlab.ui.control.NumericEditField
        matrix1_3              matlab.ui.control.NumericEditField
        matrix1_2              matlab.ui.control.NumericEditField
        matrix1                matlab.ui.control.NumericEditField
        Label_4                matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        CenterPanel            matlab.ui.container.Panel
        Label                  matlab.ui.control.Label
        Button_clear           matlab.ui.control.Button
        z_EditField            matlab.ui.control.NumericEditField
        answer_zLabel          matlab.ui.control.Label
        y_EditField            matlab.ui.control.NumericEditField
        answer_yLabel          matlab.ui.control.Label
        x_EditField            matlab.ui.control.NumericEditField
        answer_xLabel          matlab.ui.control.Label
        thirdc_coe             matlab.ui.control.NumericEditField
        thirdz_coe             matlab.ui.control.NumericEditField
        thirdequation_zLabel   matlab.ui.control.Label
        thirdy_coe             matlab.ui.control.NumericEditField
        thirdequation_yLabel   matlab.ui.control.Label
        thirdx_coe             matlab.ui.control.NumericEditField
        thirdequation_xLabel   matlab.ui.control.Label
        secondc_coe            matlab.ui.control.NumericEditField
        secondz_coe            matlab.ui.control.NumericEditField
        secondequation_zLabel  matlab.ui.control.Label
        secondy_coe            matlab.ui.control.NumericEditField
        secondequation_yLabel  matlab.ui.control.Label
        secondx_coe            matlab.ui.control.NumericEditField
        secondequation_xLabel  matlab.ui.control.Label
        firstc_coe             matlab.ui.control.NumericEditField
        firstz_coe             matlab.ui.control.NumericEditField
        firstequation_zLabel   matlab.ui.control.Label
        firsty_coe             matlab.ui.control.NumericEditField
        firstequation_yLabel   matlab.ui.control.Label
        firstx_coe             matlab.ui.control.NumericEditField
        firstequation_xLabel   matlab.ui.control.Label
        system_draw            matlab.ui.control.Label
        graph                  matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        RightPanel             matlab.ui.container.Panel
        Button_determ_7        matlab.ui.control.Button
        Button_determ_5        matlab.ui.control.Button
        Button_determ_3        matlab.ui.control.Button
        answer_xLabel_7        matlab.ui.control.Label
        EditField_4            matlab.ui.control.NumericEditField
        answer_xLabel_6        matlab.ui.control.Label
        EditField_3            matlab.ui.control.NumericEditField
        answer_xLabel_5        matlab.ui.control.Label
        answer_xLabel_4        matlab.ui.control.Label
        EditField_2            matlab.ui.control.NumericEditField
        answer_xLabel_3        matlab.ui.control.Label
        EditField              matlab.ui.control.NumericEditField
        answer_xLabel_2        matlab.ui.control.Label
        Label_7                matlab.ui.control.Label
        UIAxes_2               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: graph
        function graphButtonPushed(app, event)
            % Присвояване на коефициентите на уравненията в нови променливи
a1 = app.firstx_coe.Value;
a2 = app.firsty_coe.Value;
a3 = app.firstz_coe.Value;
a4 = app.firstc_coe.Value;
b1 = app.secondx_coe.Value;
b2 = app.secondy_coe.Value;
b3 = app.secondz_coe.Value;
b4 = app.secondc_coe.Value;
c1 = app.thirdx_coe.Value;
c2 = app.thirdy_coe.Value;
c3 = app.thirdz_coe.Value;
c4 = app.thirdc_coe.Value;

% Съставяне на матрицата на коефициентите и вектора свободни членове
A = [a1, a2, a3; b1, b2, b3; c1, c2, c3];
B = [a4; b4; c4];

% Решаване на системата от линейни уравнения
solution = linsolve(A, B);

% Извличане на стойностите на x, y и z от решението
x = solution(1);
y = solution(2);
z = solution(3);

% Присвояване на стойностите на x, y, z в съответните EditFields
app.x_EditField.Value = x;
app.y_EditField.Value = y;
app.z_EditField.Value = z;

% Изчертаване на графиката в тримерно пространство на app.UIAxes
plot3(app.UIAxes, x, y, z, 'ro', 'MarkerSize', 10); % Червени точки за решението
grid(app.UIAxes, 'on');
xlabel(app.UIAxes, 'X');
ylabel(app.UIAxes, 'Y');
zlabel(app.UIAxes, 'Z');

% Изчертаване на трите равнини
hold(app.UIAxes, 'on');

[xGrid, yGrid] = meshgrid(-10:0.5:10, -10:0.5:10);

zGrid1 = (a4 - a1 * xGrid - a2 * yGrid) / a3; % Първо уравнение
zGrid2 = (b4 - b1 * xGrid - b2 * yGrid) / b3; % Второ уравнение
zGrid3 = (c4 - c1 * xGrid - c2 * yGrid) / c3; % Трето уравнение

surf(app.UIAxes, xGrid, yGrid, zGrid1, 'FaceColor', 'r', 'FaceAlpha', 0.5); % Червена повърхност за първото уравнение
surf(app.UIAxes, xGrid, yGrid, zGrid2, 'FaceColor', 'b', 'FaceAlpha', 0.5); % Синя повърхност за второто уравнение
surf(app.UIAxes, xGrid, yGrid, zGrid3, 'FaceColor', 'm', 'FaceAlpha', 0.5); % Оранжева повърхност за третото уравнение
        end

        % Button pushed function: Button_determ
        function Button_determPushed(app, event)
            d1=app.matrix1.Value;
            d2=app.matrix1_2.Value;
            d3=app.matrix1_3.Value;
            d4=app.matrix1_4.Value;
            d5=app.matrix1_5.Value;
            d6=app.matrix1_6.Value;
            d7=app.matrix1_7.Value;
            d8=app.matrix1_8.Value;
            d9=app.matrix1_9.Value;
            A = [d1 d2 d3; d4 d5 d6; d7 d8 d9];
            determinant = det(A);
            app.EditField_det.Value = determinant;
          
        end

        % Button pushed function: Button_determ_2
        function Button_determ_2Pushed(app, event)
            d1=app.matrix1.Value;
            d2=app.matrix1_2.Value;
            d3=app.matrix1_3.Value;
            d4=app.matrix1_4.Value;
            d5=app.matrix1_5.Value;
            d6=app.matrix1_6.Value;
            d7=app.matrix1_7.Value;
            d8=app.matrix1_8.Value;
            d9=app.matrix1_9.Value;
            A = [d1 d2 d3; d4 d5 d6; d7 d8 d9];
            matrix_rank = rank(A);
            app.EditField_rang.Value=matrix_rank;
        end

        % Button pushed function: clear_matrix
        function clear_matrixButtonPushed(app, event)
            app.matrix1.Value = 0;
            app.matrix1_2.Value = 0;
            app.matrix1_3.Value = 0;
            app.matrix1_4.Value = 0;
            app.matrix1_5.Value = 0;
            app.matrix1_6.Value = 0;
            app.matrix1_7.Value = 0;
            app.matrix1_8.Value = 0;
            app.matrix1_9.Value = 0;
            app.EditField_rang.Value = 0;
            app.EditField_det.Value = 0;
        end

        % Button pushed function: Button_clear
        function Button_clearPushed(app, event)
            app.firstx_coe.Value = 0;
            app.firsty_coe.Value = 0;
            app.firstz_coe.Value = 0;
            app.firstc_coe.Value = 0;
            app.secondx_coe.Value = 0;
            app.secondy_coe.Value = 0;
            app.secondz_coe.Value = 0;
            app.secondc_coe.Value = 0;
            app.thirdx_coe.Value = 0;
            app.thirdy_coe.Value = 0;
            app.thirdz_coe.Value = 0;
            app.thirdc_coe.Value = 0;
            app.x_EditField.Value = 0;
            app.y_EditField.Value = 0;
            app.z_EditField.Value = 0;
            % Изчистване на текущия график
            cla(app.UIAxes);
            % Промяна на изгледа на графиката, за да се върне в двумерно пространство
            view(app.UIAxes, 2);
        end

        % Button pushed function: Button_determ_3
        function Button_determ_3Pushed(app, event)
         % Вземете координатите на точките A и B от EditFields
    x1 = app.EditField.Value;
    y1 = app.EditField_2.Value;
    x2 = app.EditField_3.Value;
    y2 = app.EditField_4.Value;

    % Проверете дали координатите са валидни
    if isnan(x1) || isnan(y1) || isnan(x2) || isnan(y2)
        % Покажете съобщение за грешка, ако координатите не са валидни
        errordlg('Моля, въведете валидни координати за точките A и B.');
        return;
    end

    % Изчислете наклона на правата
    m = (y2 - y1) / (x2 - x1);

    % Изчислете смещението на правата (y-координата при x = 0)
    b = y1 - m * x1;

    % Генерирайте точки за x в интервала между x1 и x2
    x = linspace(x1, x2, 200);

    % Изчислете съответните y координати, използвайки уравнението на правата
    y = m * x + b;

    % Изчертайте точките A и B на графиката
    plot(app.UIAxes_2, [x1, x2], [y1, y2], 'o', 'MarkerSize', 8, 'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'r'); % Точки A и B

    % Изчертайте правата
    hold(app.UIAxes_2, 'on'); % Задръжте текущият график
    plot(app.UIAxes_2, x, y, 'b', 'LineWidth', 2); % Права

    hold(app.UIAxes_2, 'off'); % Освободете графика

    % Настройте основния надпис и надписите на осите
    xlabel(app.UIAxes_2, 'X');
    ylabel(app.UIAxes_2, 'Y');
    title(app.UIAxes_2, 'Уравнение на права, минаваща през A и B');
        end

        % Button pushed function: Button_determ_5
        function Button_determ_5Pushed(app, event)
             % Вземете координатите на точките A и B от EditFields
    x1 = app.EditField.Value;
    y1 = app.EditField_2.Value;
    x2 = app.EditField_3.Value;
    y2 = app.EditField_4.Value;

    % Проверете дали координатите са валидни
    if isnan(x1) || isnan(y1) || isnan(x2) || isnan(y2)
        % Покажете съобщение за грешка, ако координатите не са валидни
        errordlg('Моля, въведете валидни координати за точките A и B.');
        return;
    end

    % Изчислете средната точка между A и B
    xm = (x1 + x2) / 2;
    ym = (y1 + y2) / 2;

    % Изчисява параметрите a и b на хиперболата
    c = sqrt((x2 - x1)^2 + (y2 - y1)^2) / 2;
    a = c + abs(xm - x1);
    b = sqrt(c^2 - ((x2 - x1) / 2)^2);

    % Генерирайте точки за x в интервала [xm - 2*a, xm + 2*a]
    x = linspace(xm - 2 * a, xm + 2 * a, 200);

    % Изчислете съответните y координати, използвайки уравнението на хиперболата
    y_positive = ym + b * sqrt(1 + ((x - xm) / a).^2);
    y_negative = ym - b * sqrt(1 + ((x - xm) / a).^2);

    % Изчертайте точките A и B на графиката
    plot(app.UIAxes_2, [x1, x2], [y1, y2], 'o', 'MarkerSize', 8, 'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'r'); % Точки A и B

    % Изчертайте хиперболата
    hold(app.UIAxes_2, 'on'); % Задръжте текущият график
    plot(app.UIAxes_2, x, y_positive, 'b', 'LineWidth', 2); % Горна ветв на хиперболата
    plot(app.UIAxes_2, x, y_negative, 'b', 'LineWidth', 2); % Долна ветв на хиперболата
    hold(app.UIAxes_2, 'off'); % Освободете графика

    % Настройте основния надпис и надписите на осите
    xlabel(app.UIAxes_2, 'X');
    ylabel(app.UIAxes_2, 'Y');
    title(app.UIAxes_2, 'Уравнение на хипербола, минаваща през A и B');
        end

        % Button pushed function: Button_determ_7
        function Button_determ_7Pushed(app, event)
             % Изчистете графиката
    cla(app.UIAxes_2);

    % Променете стойностите на точките на нули
    app.EditField.Value = 0;
    app.EditField_2.Value = 0;
    app.EditField_3.Value = 0;
    app.EditField_4.Value = 0;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {653, 653, 653};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {653, 653};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {343, '1x', 548};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = 'Детерминанта на матрица';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '|';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '|';
            app.matrix1 = uieditfield(app.LeftPanel, 'numeric');
            app.matrix1_2 = uieditfield(app.LeftPanel, 'numeric');
            app.matrix1_3 = uieditfield(app.LeftPanel, 'numeric');
            app.matrix1_4 = uieditfield(app.LeftPanel, 'numeric');
            app.matrix1_5 = uieditfield(app.LeftPanel, 'numeric');
            app.matrix1_6 = uieditfield(app.LeftPanel, 'numeric');
            app.matrix1_7 = uieditfield(app.LeftPanel, 'numeric');
            app.matrix1_8 = uieditfield(app.LeftPanel, 'numeric');
            app.matrix1_9 = uieditfield(app.LeftPanel, 'numeric');
            app.Button_determ = uibutton(app.LeftPanel, 'push');
            app.Button_determ.ButtonPushedFcn = createCallbackFcn(app, @Button_determPushed, true);
            app.Button_determ.Text = 'Детерминанта';
            app.Button_determ_2 = uibutton(app.LeftPanel, 'push');
            app.Button_determ_2.ButtonPushedFcn = createCallbackFcn(app, @Button_determ_2Pushed, true);
            app.Button_determ_2.Text = 'Ранг';
            app.clear_matrix = uibutton(app.LeftPanel, 'push');
            app.clear_matrix.ButtonPushedFcn = createCallbackFcn(app, @clear_matrixButtonPushed, true);
            app.clear_matrix.Text = 'Изчисти';
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = 'Детерминанта:';
            app.EditField_det = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_det.Editable = 'off';
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = 'Ранг:';
            app.EditField_rang = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_rang.Editable = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Графично представяне на система линейни уравнения')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.graph = uibutton(app.CenterPanel, 'push');
            app.graph.ButtonPushedFcn = createCallbackFcn(app, @graphButtonPushed, true);
            app.graph.Text = 'Покажи графиката и корените';
            app.system_draw = uilabel(app.CenterPanel);
            app.system_draw.Text = '|';
            app.firstequation_xLabel = uilabel(app.CenterPanel);
            app.firstequation_xLabel.Interpreter = 'latex';
            app.firstequation_xLabel.Text = ' x+';
            app.firstx_coe = uieditfield(app.CenterPanel, 'numeric');
            app.firstequation_yLabel = uilabel(app.CenterPanel);
            app.firstequation_yLabel.Interpreter = 'latex';
            app.firstequation_yLabel.Text = 'y+';
            app.firsty_coe = uieditfield(app.CenterPanel, 'numeric');
            app.firstequation_zLabel = uilabel(app.CenterPanel);
            app.firstequation_zLabel.Interpreter = 'latex';
            app.firstequation_zLabel.Text = 'z=';
            app.firstz_coe = uieditfield(app.CenterPanel, 'numeric');
            app.firstc_coe = uieditfield(app.CenterPanel, 'numeric');
            app.secondequation_xLabel = uilabel(app.CenterPanel);
            app.secondequation_xLabel.Interpreter = 'latex';
            app.secondequation_xLabel.Text = ' x+';
            app.secondx_coe = uieditfield(app.CenterPanel, 'numeric');
            app.secondequation_yLabel = uilabel(app.CenterPanel);
            app.secondequation_yLabel.Interpreter = 'latex';
            app.secondequation_yLabel.Text = 'y+';
            app.secondy_coe = uieditfield(app.CenterPanel, 'numeric');
            app.secondequation_zLabel = uilabel(app.CenterPanel);
            app.secondequation_zLabel.Interpreter = 'latex';
            app.secondequation_zLabel.Text = 'z=';
            app.secondz_coe = uieditfield(app.CenterPanel, 'numeric');
            app.secondc_coe = uieditfield(app.CenterPanel, 'numeric');
            app.thirdequation_xLabel = uilabel(app.CenterPanel);
            app.thirdequation_xLabel.Interpreter = 'latex';
            app.thirdequation_xLabel.Text = ' x+';
            app.thirdx_coe = uieditfield(app.CenterPanel, 'numeric');
            app.thirdequation_yLabel = uilabel(app.CenterPanel);
            app.thirdequation_yLabel.Interpreter = 'latex';
            app.thirdequation_yLabel.Text = 'y+';
            app.thirdy_coe = uieditfield(app.CenterPanel, 'numeric');
            app.thirdequation_zLabel = uilabel(app.CenterPanel);
            app.thirdequation_zLabel.Interpreter = 'latex';
            app.thirdequation_zLabel.Text = 'z=';
            app.thirdz_coe = uieditfield(app.CenterPanel, 'numeric');
            app.thirdc_coe = uieditfield(app.CenterPanel, 'numeric');
            app.answer_xLabel = uilabel(app.CenterPanel);
            app.answer_xLabel.Interpreter = 'latex';
            app.answer_xLabel.Text = ' x=';
            app.x_EditField = uieditfield(app.CenterPanel, 'numeric');
            app.x_EditField.Editable = 'off';
            app.answer_yLabel = uilabel(app.CenterPanel);
            app.answer_yLabel.Interpreter = 'latex';
            app.answer_yLabel.Text = ' y=';
            app.y_EditField = uieditfield(app.CenterPanel, 'numeric');
            app.y_EditField.Editable = 'off';
            app.answer_zLabel = uilabel(app.CenterPanel);
            app.answer_zLabel.Interpreter = 'latex';
            app.answer_zLabel.Text = ' z=';
            app.z_EditField = uieditfield(app.CenterPanel, 'numeric');
            app.z_EditField.Editable = 'off';
            app.Button_clear = uibutton(app.CenterPanel, 'push');
            app.Button_clear.ButtonPushedFcn = createCallbackFcn(app, @Button_clearPushed, true);
            app.Button_clear.Text = 'Изчисти';
            app.Label = uilabel(app.CenterPanel);
            app.Label.Text = 'Система линейни уравнения от трети ред';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Графично представяне на  уравнениe')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Label_7 = uilabel(app.RightPanel);
            app.Label_7.Text = 'Скаларно параметрично и канонично уравнение';
            app.answer_xLabel_2 = uilabel(app.RightPanel);
            app.answer_xLabel_2.Interpreter = 'latex';
            app.answer_xLabel_2.Text = ' т. А (';
            app.EditField = uieditfield(app.RightPanel, 'numeric');
            app.answer_xLabel_3 = uilabel(app.RightPanel);
            app.answer_xLabel_3.Interpreter = 'latex';
            app.answer_xLabel_3.Text = ' ;';
            app.EditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.answer_xLabel_4 = uilabel(app.RightPanel);
            app.answer_xLabel_4.Interpreter = 'latex';
            app.answer_xLabel_4.Text = ')';
            app.answer_xLabel_5 = uilabel(app.RightPanel);
            app.answer_xLabel_5.Interpreter = 'latex';
            app.answer_xLabel_5.Text = ' т. B (';
            app.EditField_3 = uieditfield(app.RightPanel, 'numeric');
            app.answer_xLabel_6 = uilabel(app.RightPanel);
            app.answer_xLabel_6.Interpreter = 'latex';
            app.answer_xLabel_6.Text = ' ;';
            app.EditField_4 = uieditfield(app.RightPanel, 'numeric');
            app.answer_xLabel_7 = uilabel(app.RightPanel);
            app.answer_xLabel_7.Interpreter = 'latex';
            app.answer_xLabel_7.Text = ')';
            app.Button_determ_3 = uibutton(app.RightPanel, 'push');
            app.Button_determ_3.ButtonPushedFcn = createCallbackFcn(app, @Button_determ_3Pushed, true);
            app.Button_determ_3.Text = 'Права';
            app.Button_determ_5 = uibutton(app.RightPanel, 'push');
            app.Button_determ_5.ButtonPushedFcn = createCallbackFcn(app, @Button_determ_5Pushed, true);
            app.Button_determ_5.Text = 'Хипербола';
            app.Button_determ_7 = uibutton(app.RightPanel, 'push');
            app.Button_determ_7.ButtonPushedFcn = createCallbackFcn(app, @Button_determ_7Pushed, true);
            app.Button_determ_7.Text = 'Изчисти';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matrix_system

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end